% description: Copyright 2018 Luca Della Santina
classdef Clarinet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ClarinetUIFigure           matlab.ui.Figure
        Lamp                       matlab.ui.control.Lamp
        Log                        matlab.ui.control.EditField
        UIAxes                     matlab.ui.control.UIAxes
        pnlToolbar                 matlab.ui.container.Panel
        btnFind                    matlab.ui.control.Button
        btnOpenData                matlab.ui.control.Button
        btnAbout                   matlab.ui.control.Button
        btnExport                  matlab.ui.control.Button
        btnSave                    matlab.ui.control.Button
        btnProcess                 matlab.ui.control.Button
        btnSettings                matlab.ui.control.Button
        cmbProcessors              matlab.ui.control.DropDown
        btnBatchAdd                matlab.ui.control.Button
        btnBatchClear              matlab.ui.control.Button
        lblProcessors              matlab.ui.control.Label
        btnBatchOpen               matlab.ui.control.Button
        btnBatchSave               matlab.ui.control.Button
        btnBatchApply              matlab.ui.control.Button
        XScaleType                 matlab.ui.control.Switch
        btnGroupVisualizationType  matlab.ui.container.ButtonGroup
        btnPlot2D                  matlab.ui.control.ToggleButton
        btnPlot3D                  matlab.ui.control.ToggleButton
        UITree                     matlab.ui.container.Tree
        pnlEpochTools              matlab.ui.container.ButtonGroup
        btnTagEmpty                matlab.ui.control.ToggleButton
        btnTagYellow               matlab.ui.control.ToggleButton
        btnTagGreen                matlab.ui.control.ToggleButton
        btnTagOrange               matlab.ui.control.ToggleButton
        btnTagBlue                 matlab.ui.control.ToggleButton
        btnTagRed                  matlab.ui.control.ToggleButton
        btnTagBlack                matlab.ui.control.ToggleButton
        btnEpochTypes              matlab.ui.control.Switch
        btnTagRemove               matlab.ui.control.Button
        btnTagAdd                  matlab.ui.control.Button
        btnResponseType            matlab.ui.control.Switch
        tblMeta                    matlab.ui.control.Table
        Mode                       matlab.ui.control.Switch
        pnlFeatureTools            matlab.ui.container.Panel
        btnTreeRemove              matlab.ui.control.Button
        btnTreeClear               matlab.ui.control.Button
        btnGroupByTag              matlab.ui.control.Button
        btnGroupAdd                matlab.ui.control.Button
        cmbFeatures                matlab.ui.control.DropDown
        cmbDevices                 matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        Cells           = [] % List of cells to extract features from
        Conditions      = [] % Search conditions table
        SelectedEpochs  = [] % List of Epoch indexes seected using findEpochs
        dlgFind         = [] % Dialog window used for predicate search
        dlgAbout        = [] % Dialog window showing app info
        dlgGroupBy      = [] % Dialog window to group epochs by tag values
        pipeline        = [] % Processing Pipeline list
        rootNodeCells   = [] % Root node handle of the UITree component in Epochs mode
        rootNodeAnalysis= [] % Root node handle of the UITree component in Features mode
        LocalFolder     = '' % Local folder in which settings and custom processors are stored
        selectedTagRows = [] % Row indexes the user selected in the Tags table
        ICON_GROUP      = 'group.png'
        ICON_TAG        = 'tag.png'
        ICON_EXTRACTOR  = 'extractor.png'
    end
    
    methods (Access = private)
        
        function populateUITree(app)
            app.UITree.clo; % Clear previous tree
            app.tblMeta.Data = [];
            if isempty(app.Cells)
                return
            end
            
            switch app.Mode.Value
                case 'Epochs'
                    app.rootNodeCells = uitreenode(app.UITree,'Text','Epochs','NodeData',[]);
                    for j = 1:numel(app.Cells)
                        exp = uitreenode(app.rootNodeCells,'Text',[app.Cells(j).get('label') ' (' app.Cells(j).experimentDate ')'],'NodeData',[]);
                        for i = 1:numel(app.Cells(j).epochs)
                            epoch = app.Cells(j).epochs(i);
                            if strcmp(app.btnEpochTypes.Value, 'Searched') && (isempty(epoch.filtered) || ~epoch.filtered)
                                continue
                            end
                            
                            if epoch.hasAttribute('tagColor') && ~strcmp(epoch.attributes('tagColor'),'')
                                tagColor = [epoch.attributes('tagColor') '.png'];
                            else
                                tagColor = '';
                                epoch.attributes('tagColor') = tagColor;
                            end
                            uitreenode(exp,'Text',['Epoch ' num2str(epoch.get('epochNum')) datestr(epoch.get('epochTime'), ' (mmm dd, HH:MM:SS)')],...
                                'NodeData',epoch,'Icon', tagColor);
                        end
                    end
                case 'Features'
                    app.rootNodeAnalysis = uitreenode(app.UITree,'Text', 'Features','NodeData',[]);
                    for i = 1:numel(app.Cells)
                        uitreenode(app.rootNodeAnalysis,'Text',app.Cells(i).get('label'),'NodeData',app.Cells(i), 'Icon', app.ICON_GROUP);
                    end
                    
                    if numel(app.Cells) > 0
                        app.btnTreeRemove.Enable = 'on';
                        app.btnTreeClear.Enable  = 'on';
                    end
            end                    
            app.UITree.expand('all');
        end
        
        function plotEpoch(app, epochs)
            cla(app.UIAxes,'reset');
            app.UIAxes.XGrid = true;
            app.UIAxes.YGrid = true;
            app.UIAxes.ZGrid = true;
            
            epochNames = repmat({''}, numel(epochs),1);
            for i = 1:numel(epochs)
                epochNames{i} = ['Epoch ' num2str(epochs(i).get('epochNum'))]; %Remember epoch names for legend
                switch app.btnResponseType.Value
                    case 'Raw'
                        response = epochs(i).getResponse(app.cmbDevices.Value);
                    case 'Processed'
                        if epochs(i).hasDerivedResponse('filteredResponse', app.cmbDevices.Value)
                            response = epochs(i).getDerivedResponse('filteredResponse', app.cmbDevices.Value);
                        else
                            disp('No filtered Response available, using raw');
                            response = epochs(i).getResponse(app.cmbDevices.Value);
                        end
                end
                
                % Plot epoch in a 3D space if button is pressed, otherwise use 2D cartesian plane
                if app.btnPlot3D.Value
                    plot3(app.UIAxes, 1:numel(response.quantity), repmat(i, 1, numel(response.quantity)), response.quantity');
                else
                    plot(app.UIAxes, 1:numel(response.quantity), response.quantity');
                end
                
                % Define the range to display in the X and Y axis
                if i == 1
                    Xmax = numel(response.quantity);
                    Ymax = max(response.quantity);
                    Ymin = min(response.quantity);
                else
                    Xmax = max([Xmax, numel(response.quantity)]);
                    Ymax = max([Ymax, max(response.quantity)]);
                    Ymin = min([Ymin, min(response.quantity)]);
                end
                
                if numel(epochs)>1
                    hold(app.UIAxes, 'on');
                end
            end
            
            % Handle special cases before setting the Y axis
            if Ymin == Ymax % no signal or signal has a constant value
                Ymin = Ymin -1;
                Ymax = Ymax +1;
            elseif Ymin==0 && Ymax==1 % binary value (0-1)
                Ymin = -0.3;
                Ymax = 1.3;
            end
            
            if app.btnPlot3D.Value
                xlim(app.UIAxes, [1 Xmax]);
                ylim(app.UIAxes, [0 numel(epochs)+1]);
                zlim(app.UIAxes, [Ymin Ymax]);
            else
                xlim(app.UIAxes, [1 Xmax]);
                ylim(app.UIAxes, [Ymin Ymax]);
            end
            if isfield(response, 'units')
                units = response.('units');
                units = deblank(units(:,1)');
                ylabel(app.UIAxes, units);
            else
                ylabel(app.UIAxes, '');
            end
            title(app.UIAxes, epochs(1).get('displayName'));

            
            switch app.XScaleType.Value
                case 'Time (s)'     % Relabel 'XTick' With 'XTickLabel' Values scaled by sampleRate
                    meta = epochs(1).toStructure;
                    xt = get(app.UIAxes, 'XTick');
                    set(app.UIAxes, 'XTick', xt, 'XTickLabel', xt/meta.sampleRate);
                case 'Samples'      % Use original XTickLabels
                    xt = get(app.UIAxes, 'XTick');
                    set(app.UIAxes, 'XTick', xt, 'XTickLabel', xt);
            end
            legend(app.UIAxes, epochNames, 'Location','northeast');
        end
        
        function populateFilterList(app)
            switch app.Mode.Value
                case 'Epochs'            
                    import processors.*
                    filters = meta.package.fromName('processors').FunctionList;
                    filterNames = {filters.Name};
                    filterNames = sort(filterNames);
                    
                    import customProcessors.*
                    if ~isempty(meta.package.fromName('customProcessors'))
                        customFilters = meta.package.fromName('customProcessors').FunctionList;
                        customFilterNames = {customFilters.Name};
                        customFilterNames = sort(customFilterNames);
                        app.cmbProcessors.Items = [customFilterNames, filterNames];
                    else
                        app.cmbProcessors.Items = filterNames;
                    end
                    app.cmbProcessorsValueChanged;
                case 'Features'
                    import extractors.*
                    filters = meta.package.fromName('extractors').FunctionList;
                    filterNames = {filters.Name};
                    filterNames = sort(filterNames); 
                    app.cmbProcessors.Items = filterNames;
                    app.cmbProcessorsValueChanged;                  
            end            
            
            % Reset the pipeline because processors/extractors in there might not be available anymore
            app.pipeline = [];
        end


        function plotFeature(app, group, featureName)
            cla(app.UIAxes,'reset');
            app.UIAxes.XGrid = true;
            app.UIAxes.YGrid = true;
            app.UIAxes.ZGrid = true;

            dataGroup = group.getFeatureData(featureName); % Can either be a cell array or an array of struct            
            for i=1:numel(dataGroup)
                switch class(dataGroup)
                    case 'cell'
                        data = dataGroup{i};
                    case 'struct'
                        data = dataGroup(i).quantity;
                end
                
                % Plot epoch in a 3D space if button is pressed, otherwise use 2D cartesian plane
                if i>1
                    hold(app.UIAxes, 'on');
                end
                if app.btnPlot3D.Value
                    plot3(app.UIAxes, 1:numel(data), repmat(i, 1, numel(data)), data);
                else
                    plot(app.UIAxes, 1:numel(data), data);
                end
                
                % Define the range to display in the X and Y axis
                if i == 1
                    Xmax = numel(data);
                    Ymax = max(data);
                    Ymin = min(data);
                else
                    Xmax = max([Xmax, numel(data)]);
                    Ymax = max([Ymax, max(data)]);
                    Ymin = min([Ymin, min(data)]);
                end                
            end
            
            % Handle special cases before setting the Y axis
            if Ymin == Ymax % no signal or signal has a constant value
                Ymin = Ymin -1;
                Ymax = Ymax +1;
            elseif Ymin==0 && Ymax==1 % binary value (0-1)
                Ymin = -0.3;
                Ymax = 1.3;
            end
            
            if app.btnPlot3D.Value
                xlim(app.UIAxes, [1 Xmax]);
                ylim(app.UIAxes, [0 numel(dataGroup)+1]);
                zlim(app.UIAxes, [Ymin Ymax]);
            else
                xlim(app.UIAxes, [1 Xmax]);
                ylim(app.UIAxes, [Ymin Ymax]);
            end
            
            feat = group.getFeatures(featureName);
            %feat(1).description
            if isfield(feat(1).description, 'units')
                ylabel(app.UIAxes, feat(1).description.units);
            else
                ylabel(app.UIAxes, '');
            end
        end
        
        function cellData = findParentCellData(~, node)
            % Search upstream in the tree for the node holding CellData
            currentNode = node;
            while ~isempty(currentNode.Parent)
                data = currentNode.NodeData;
                if isa(data, 'CellData')
                    cellData = data;
                    return
                end               
                currentNode = currentNode.Parent;
            end
        end
        
        function sharedKeys = getFeatureKeysAcrossCells(app)
            % Trasverse the tree to find Feature keys shared across experiments
            sharedKeys = {};
            CellDataNodes = app.rootNodeAnalysis.Children;
            for i=1:numel(CellDataNodes)
                node = CellDataNodes(i);
                for j = 1: numel(node.Children)
                    child = node.Children(j);
                    data = child.NodeData;
                    keys = data.getFeatureKey;
                    
                    % Accumulate feature only if shared across cells examined so far
                    if isempty(sharedKeys)
                        sharedKeys = keys;
                    else
                        sharedKeys = intersect(sharedKeys, keys);
                    end
                end
            end
        end
        
        function splitMap = getSplitMapAcrossExperiments(app)
            % Trasverse the tree to find Feature keys shared across experiments
            splitMap = containers.Map; 
            CellDataNodes = app.rootNodeAnalysis.Children;
            for i=1:numel(CellDataNodes)
                node = CellDataNodes(i);
                for j = 1: numel(node.Children)
                    child = node.Children(j);
                    data = child.NodeData;
                    
                    % Accumulate splitParameter/splitValue pairs found across cells
                    if ~splitMap.isKey(data.splitParameter)
                        splitMap(data.splitParameter) = {data.splitValue};
                    else
                        value = splitMap(data.splitParameter);
                        value{end+1} = data.splitValue;
                        splitMap(data.splitParameter) = value;
                    end
                end
           end
        end    
    end
    
    methods (Access = public)
        
        function result = findEpochs(app, conditions, criteria)
            % Search for epoch matching the stated conditions according to a specific criteria
            % conditions = table of conditions, each row contains: [ propery | operator | value ]
            % criteria = [ Any | All ] (e.g. "Any" means query returns epochs which meet any of the conditions)
            
            CONDITIONS_MAP = containers.Map({'numeric', 'string'},...
                {{'==', '>', '<', '>=', '<=', '~='}, {'strcmpi', 'strfind', 'strcmp', 'regexp'}});
            epochs = app.Cells.epochs; % TODO fix search across cells
            
            arrayfun(@(e) setFiltered(e, false), epochs)
            query = linq(1 : numel(epochs));
            
            rows = size(conditions, 1);
            for row = 1 : rows
                property    = conditions{row, 1}; % returns a cell with the property
                property    = property{:};
                operator    = conditions{row, 2};
                operator    = string(operator);
                value       = conditions{row, 3}; % returns a cell with the value
                value       = value{:}; % needed to retrieve the actual values from a cell
                
                % find out whether current operator is numeric or string
                operatorIdx = cellfun(@(keySet) ismember(operator, keySet), CONDITIONS_MAP.values);
                keys        = CONDITIONS_MAP.keys;
                operatorType= keys{operatorIdx};
                
                if strcmp(operatorType, 'numeric')
                    predicateCondition = str2func(strcat('@(data, values) any(data.get(''', property, ''')', operator, 'values)'));
                    value = str2double(value);
                else
                    predicateCondition = str2func(strcat('@(data, values) any(', operator, '(data.get(''', property, '''), values))'));
                end
                
                predicate = @(data) predicateCondition(data, value);
                switch criteria
                    case 'All'
                        query = query.where(@(index) predicate(epochs(index)));
                    case 'Any'
                        if row == 1
                            query = query.where(@(index) predicate(epochs(index)));
                        else
                            newquery = linq(1 : numel(epochs));
                            newquery = newquery.where(@(index) predicate(epochs(index)));
                            query = query.concat(newquery);
                        end
                end
            end
            filteredIndices = query.toArray();
            
            % Update the 'filtered' property of each epoch according to result
            arrayfun(@(e) setFiltered(e, true), epochs(filteredIndices));
            arrayfun(@(e) setFiltered(e, false), epochs(setdiff(1:numel(epochs), filteredIndices)));
            
            result                  = filteredIndices;
            app.SelectedEpochs      = filteredIndices;  % Store list of selected epochs indexes
            app.Conditions          = conditions;       % Store conditions table for later
            app.btnEpochTypes.Value = 'Searched';
            app.btnEpochTypesValueChanged;
            
            %if numel(filteredIndices) > 0
            %    [p, v] = app.Cell.getUniqueParamValues(filteredIndices);
            %    result = KeyValueEntity(containers.Map(p, v));
            %else
            %    result = 'No matching records found !';
            %end
            
            function setFiltered(epoch, tf)
                epoch.filtered = tf;
            end
        end
        
        function groupEpochsByTags(app, conditions)
            rows = size(conditions, 1);
            for row = 1 : rows
                tag    = conditions{row, 1}; % returns a cell with the property
                tag    = tag{:};

                for i = 1:numel(app.UITree.SelectedNodes)
                    node = app.UITree.SelectedNodes(i);
                    cellData = app.findParentCellData(node);
                    if isa(node.NodeData, 'EpochGroup')
                        oldGroup = node.NodeData;
                        mapByTagLevels = cellData.getEpochValuesMap(tag, oldGroup.epochIndices);
                    else
                        mapByTagLevels = cellData.getEpochValuesMap(tag); % [keys = unique tag levels, values = epoch indexes at that level]
                    end
                    
                    if isempty(mapByTagLevels)
                        continue
                    end
                    
                    keys = mapByTagLevels.keys;
                    for j=1:numel(keys)
                        group = EpochGroup(tag, keys{j});             % Find name of current tag
                        group.device = app.cmbDevices.Value;            % Process selected device
                        group.epochIndices = mapByTagLevels(keys{j});   % Store indexes of epochs sharing the same tag level
                        group.epochs = cellData.epochs(group.epochIndices);
                        group.populateEpochResponseAsFeature(cellData.epochs(group.epochIndices)); % Add epochs response to group
                        uitreenode(node,'Text',[tag ' = ' keys{j}],'NodeData', group, 'Icon', app.ICON_TAG);
                    end
                end                
            end            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Make sure there is a local folder in MATLAB's user path where to store settings
            if ~isfolder([userpath filesep 'Clarinet'])
                mkdir([userpath filesep 'Clarinet']);
                disp(['Creating a folder where all Clarinet settings will be stored: ' userpath filesep 'Clarinet']);
            end
            if ~isfolder([userpath filesep 'Clarinet' filesep '+customProcessors'])
                mkdir([userpath filesep 'Clarinet' filesep '+customProcessors']);
            end
            app.LocalFolder = [userpath filesep 'Clarinet'];
            addpath(app.LocalFolder);
            
            app.populateFilterList; % Load the available epoch processors
        end

        % Button pushed function: btnOpenData
        function btnOpenDataButtonPushed(app, event)
            switch app.Mode.Value
                case 'Epochs'
                    [fName, PathName] = uigetfile({'*.h5;*.mat','Supported formats (*.h5, *.mat)'; '*.h5','Symphony files (*.h5)';'*.mat','Clarinet files (*.mat)'}, 'Select experiments', 'MultiSelect', 'on');
                    app.Lamp.Color = 'Yellow';
                    app.Log.Value = 'Loading files...';
                    pause(0.2);
                    
                    app.ClarinetUIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
                    app.ClarinetUIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
                    
                    if iscell(fName)
                        cd(PathName);
                        nbfiles = length(fName);
                    elseif fName ~= 0
                        cd(PathName);
                        nbfiles = 1;
                    else
                        nbfiles = 0;
                    end
                    
                    import parsers.*
                    dlg = uiprogressdlg(app.ClarinetUIFigure,'Title','Loading files...','Indeterminate','on');
                    if nbfiles == 1
                        [~,~,ext] = fileparts(fName);
                        
                        switch ext
                            case '.h5'
                                version = SymphonyParser.getVersion(fName);
                                if version == 2
                                    ref = SymphonyV2Parser(fName);
                                else
                                    ref = SymphonyV1Parser(fName);
                                end
                                ref.parse;
                                data = ref.getResult;
                                app.Cells = data{end};
                            case '.mat'
                                data = load(fName, 'Cell');
                                app.Cells = data.Cell;
                        end
                        
                        app.populateUITree;
                        cla(app.UIAxes);
                        
                        app.btnSave.Enable      = 'on';
                        app.btnExport.Enable    = 'off';
                        app.btnFind.Enable      = 'on';
                        app.btnBatchAdd.Enable  = 'off';
                        app.btnBatchSave.Enable = 'off';
                        app.btnBatchOpen.Enable = 'on';
                        app.btnBatchClear.Enable= 'on';
                        app.btnProcess.Enable   = 'off';
                        app.btnTagEmpty.Value   = true;
                    elseif nbfiles >1
                        for i=1:numel(fName)
                        [~,~,ext] = fileparts(fName{i});
                        
                        switch ext
                            case '.h5'
                                version = SymphonyParser.getVersion(fName{i});
                                if version == 2
                                    ref = SymphonyV2Parser(fName{i});
                                else
                                    ref = SymphonyV1Parser(fName{i});
                                end
                                ref.parse;
                                data = ref.getResult;
                                if isempty(app.Cells)
                                    app.Cells = data{end};
                                else                                    
                                    app.Cells(i) = data{end};
                                end
                            case '.mat'
                                data = load(fName{i}, 'Cell');
                                if isempty(app.Cells)
                                    app.Cells = data.Cell;
                                else                                    
                                    app.Cells(i) = data.Cell;
                                end                                
                        end
                        end
                        
                        app.populateUITree;
                        cla(app.UIAxes);
                        
                        app.btnSave.Enable      = 'on';
                        app.btnExport.Enable    = 'off';
                        app.btnFind.Enable      = 'on';
                        app.btnBatchAdd.Enable  = 'off';
                        app.btnBatchSave.Enable = 'off';
                        app.btnBatchOpen.Enable = 'on';
                        app.btnBatchClear.Enable= 'on';
                        app.btnProcess.Enable   = 'off';
                        app.btnTagEmpty.Value   = true;                        
                    else
                        app.btnSave.Enable      = 'off';
                        app.btnExport.Enable    = 'off';
                        app.btnFind.Enable      = 'off';
                        app.btnBatchAdd.Enable  = 'off';
                        app.btnBatchSave.Enable = 'off';
                        app.btnBatchOpen.Enable = 'off';
                        app.btnBatchClear.Enable= 'off';
                        app.btnProcess.Enable   = 'off';
                        app.btnTagEmpty.Value   = true;
                    end
                    
                case 'Features'
                    [fName, PathName] = uigetfile({'*.mat','Clarinet experiments (*.mat)'}, 'Select experiments', 'MultiSelect', 'on');
                    app.Lamp.Color = 'Yellow';
                    app.Log.Value = 'Loading experiments...';
                    pause(0.2);
                    
                    app.ClarinetUIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
                    app.ClarinetUIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
                    
                    if iscell(fName)
                        cd(PathName);
                        nbfiles = length(fName);
                    elseif fName ~= 0
                        cd(PathName);
                        nbfiles = 1;
                    else
                        nbfiles = 0;
                    end

                    dlg = uiprogressdlg(app.ClarinetUIFigure,'Title','Loading files...','Indeterminate','on');                    
                    if nbfiles == 1
                        data = load(fullfile(PathName, fName), 'Cell');
                        if isempty(app.Cells)
                            app.Cells = data.Cell;
                        else
                            app.Cells(end+1) = data.Cell;
                        end
                        app.populateUITree;
                    elseif nbfiles >1
                        for i=1:nbfiles
                            data = load(fullfile(PathName, fName{i}), 'Cell');
                            if isempty(app.Cells)
                                app.Cells = data.Cell;
                            else
                                app.Cells(end+1) = data.Cell;
                            end
                        end
                        app.populateUITree;
                    end
            end
            close(dlg);        
            app.Lamp.Color = 'Green';
            app.Log.Value = 'Loading experiments... DONE';
            
        end

        % Selection changed function: UITree
        function UITreeSelectionChanged(app, event)
            selectedNodes = app.UITree.SelectedNodes;
            switch app.Mode.Value
                case 'Epochs'                                        
                    if numel(selectedNodes) == 1
                        epoch = selectedNodes.NodeData;     % Retrieve epoch data
                        if isempty(epoch)
                            if ~isempty(app.Cells)   
                                app.tblMeta.Data = app.Cells.toTable;
                            end
                            app.cmbDevices.Items = {};
                            app.btnTagEmpty.Value = true;
                            return
                        end
                        if epoch.hasAttribute('tagColor')
                            switch epoch.attributes('tagColor')
                                case 'yellow',  app.btnTagYellow.Value  = true;
                                case 'green',   app.btnTagGreen.Value   = true;
                                case 'orange',  app.btnTagOrange.Value  = true;
                                case 'red',     app.btnTagRed.Value     = true;
                                case 'blue',    app.btnTagBlue.Value    = true;
                                case 'black',   app.btnTagBlack.Value   = true;
                                otherwise,      app.btnTagEmpty.Value   = true;
                            end
                        else
                            app.btnTagEmpty.Value = true;
                        end
                        if epoch.hasAttribute('pipeline')
                            pipe = epoch.attributes('pipeline');
                            tip = {['Epoch ' num2str(epoch.attributes('epochNum')) ' was processed with:']};
                            for i=1:numel(pipe)
                                tip(end+1) = {[num2str(i) ') ' pipe(i).name]}; %#ok
                            end
                            app.UITree.Tooltip    = tip;
                            app.btnBatchApply.Enable = 'on';
                        else
                            app.UITree.Tooltip    = '';
                            app.btnBatchApply.Enable = 'off';
                        end
                        app.cmbDevices.Items = epoch.get('devices'); % Populate available devices
                        app.tblMeta.Data = epoch.toTable;            % Populate metadata table
                        app.plotEpoch(epoch);                        % Plot epoch data on screen
                        
                        app.btnExport.Enable    = 'on';
                        app.btnBatchAdd.Enable  = 'on';
                        app.btnTagAdd.Enable    = 'on';
                        app.btnTagRemove.Enable = 'on';
                    elseif numel(selectedNodes) > 1
                        app.tblMeta.Data= [];
                        for i= numel(selectedNodes):-1:1
                            epochs(i) = selectedNodes(i).NodeData;     % Retrieve epoch data
                        end
                        app.cmbDevices.Items = epochs(1).get('devices'); % Populate available devices
                        app.plotEpoch(epochs);               % Plot epoch data on screen
                        
                        app.btnExport.Enable    = 'on';
                        app.btnBatchAdd.Enable  = 'on';
                        app.btnTagAdd.Enable    = 'on';
                        app.btnTagRemove.Enable = 'off';
                    else
                        app.btnExport.Enable    = 'off';
                        app.btnBatchAdd.Enable  = 'off';
                        app.btnProcess.Enable   = 'off';
                        app.btnTagAdd.Enable    = 'on';
                        app.btnTagRemove.Enable = 'off';
                    end
                case 'Features'
                    if numel(selectedNodes) == 1
                        if isempty(selectedNodes.NodeData)
                            % The root node was selected
                            app.btnGroupByTag.Enable            = 'off';
                            app.btnGroupAdd.Enable              = 'off';
                            app.btnProcess.Enable               = 'off';
                            app.btnBatchAdd.Enable              = 'off';
                            app.btnBatchOpen.Enable             = 'off';
                            
                            % Try to gather common information across cells
                            keys = app.getFeatureKeysAcrossCells;
                            if ~isempty(keys)
                                app.cmbFeatures.Items = keys;
                                splitMap = app.getSplitMapAcrossExperiments;
                                splitValues = values(splitMap, splitMap.keys);
                                app.tblMeta.Data = table(splitMap.keys', splitValues');
                            else
                                app.cmbFeatures.Items = {};
                                app.tblMeta.Data = [];
                            end
                            
                            cla(app.UIAxes,'reset');
                            app.UIAxes.XGrid                = true;
                            app.UIAxes.YGrid                = true;
                            app.UIAxes.ZGrid                = true;                            
                        elseif isa(selectedNodes.NodeData, 'CellData')
                            % An experiment was selected
                            app.btnGroupByTag.Enable            = 'on';
                            app.btnGroupAdd.Enable              = 'off';
                            app.btnProcess.Enable               = 'off';
                            app.btnBatchAdd.Enable              = 'off';
                            app.btnBatchOpen.Enable             = 'off';
                            
                            cellData = selectedNodes.NodeData;
                            app.cmbDevices.Items = cellData.epochs(1).get('devices'); % Populate available devices
                            app.cmbFeatures.Items = {};
                            [params, vals] = cellData.getParamValues;
                            app.tblMeta.Data = table(params', vals');  % Populate metadata table
                            
                            cla(app.UIAxes,'reset');
                            app.UIAxes.XGrid                = true;
                            app.UIAxes.YGrid                = true;
                            app.UIAxes.ZGrid                = true;
                        elseif isa(selectedNodes.NodeData, 'EpochGroup')
                            % An EpochGroup was selected
                            app.btnGroupByTag.Enable            = 'on';
                            app.btnGroupAdd.Enable              = 'off';
                            app.btnProcess.Enable               = 'on';
                            app.btnBatchAdd.Enable              = 'on';
                            app.btnBatchOpen.Enable             = 'on';
                            
                            epochGroup = selectedNodes.NodeData;
                            app.cmbFeatures.Items = epochGroup.getFeatureKey;
                            keys = epochGroup.getFeatureKey;
                            for i=numel(keys):-1:1
                                vals{i} = epochGroup.getFeatureData(keys{i});
                            end
                            app.tblMeta.Data = table(keys',vals');
                            app.cmbFeaturesValueChanged;
                        end
                    elseif numel(selectedNodes) > 1
                        if iseach([selectedNodes.NodeData], 'CellData')
                            % All selected objects are CellData
                            app.btnGroupByTag.Enable            = 'on';
                            app.btnGroupAdd.Enable              = 'on';
                            app.btnProcess.Enable               = 'off';
                            app.btnBatchAdd.Enable              = 'off';
                            app.btnBatchOpen.Enable             = 'off';
                            
                            cellData = selectedNodes(1).NodeData;
                            app.cmbDevices.Items = cellData.epochs(1).get('devices'); % Populate available devices
                            app.cmbFeatures.Items = {};
                            [params, vals] = cellData.getParamValues;
                            app.tblMeta.Data = table(params', vals');  % Populate metadata table
                            
                            cla(app.UIAxes,'reset');
                            app.UIAxes.XGrid                    = true;
                            app.UIAxes.YGrid                    = true;
                            app.UIAxes.ZGrid                    = true;
                        elseif iseach([selectedNodes.NodeData], 'EpochGroup')
                            % All selected objects are CellData
                            app.btnGroupByTag.Enable            = 'on';
                            app.btnGroupAdd.Enable              = 'off';
                            app.btnProcess.Enable               = 'off';
                            app.btnBatchAdd.Enable              = 'off';
                            app.btnBatchOpen.Enable             = 'off';
                            
                            %cellData = selectedNodes(1).NodeData;
                            %app.cmbDevices.Items = cellData.epochs(1).get('devices'); % Populate available devices
                            %app.cmbFeatures.Items = {};
                            %[params, vals] = cellData.getParamValues;
                            %app.tblMeta.Data = table(params', vals');  % Populate metadata table
                            
                            cla(app.UIAxes,'reset');
                            app.UIAxes.XGrid                    = true;
                            app.UIAxes.YGrid                    = true;
                            app.UIAxes.ZGrid                    = true;                            
                        else
                            % Different types of objects were selected
                            app.btnGroupByTag.Enable            = 'off';
                            app.btnGroupAdd.Enable              = 'off';
                            app.btnProcess.Enable               = 'off';
                            app.btnBatchAdd.Enable              = 'off';
                            app.btnBatchOpen.Enable             = 'off';
                            
                            app.cmbDevices.Items            = {};
                            app.cmbFeatures.Items           = {};
                            app.tblMeta.Data                = [];
                            
                            cla(app.UIAxes,'reset');
                            app.UIAxes.XGrid                = true;
                            app.UIAxes.YGrid                = true;
                            app.UIAxes.ZGrid                = true;
                        end
                    end
            end
        end

        % Button pushed function: btnExport
        function btnExportButtonPushed(app, event)
            selectedNodes = app.UITree.SelectedNodes;
            
            if numel(selectedNodes) >= 1
                for i= numel(selectedNodes):-1:1
                    epoch = selectedNodes(i).NodeData;
                    meta = epoch.toStructure;
                    
                    % Filter the raw response if no previous filter was applied or user selected 'Raw'
                    switch app.btnResponseType.Value
                        case 'Raw'
                            response = epoch.getResponse(app.cmbDevices.Value);
                        case 'Processed'
                            if epoch.hasDerivedResponse('filteredResponse', app.cmbDevices.Value)
                                response = epoch.getDerivedResponse('filteredResponse', app.cmbDevices.Value);
                            else
                                response = epoch.getResponse(app.cmbDevices.Value); % Use raw response if no filtered is available
                            end
                    end
                    
                    epochs(i).epoch = response.quantity'; % Retrieve epoch data
                    epochs(i).meta  = meta;               % Retrieve epoch metadata
                end
                uisave({'epochs'},'SelectedEpochs.mat');
                
                app.ClarinetUIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
                app.ClarinetUIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
            end
        end

        % Button pushed function: btnAbout
        function btnAboutButtonPushed(app, event)
            if isempty(app.dlgAbout)  || ~isprop(app.dlgAbout,'UIFigure')
                app.dlgAbout = DialogAbout;

                % Center the dialog on main window
                pos = app.dlgAbout.UIFigure.Position;
                posClarinet = app.ClarinetUIFigure.Position;
                pos(1) = ceil(posClarinet(1)+posClarinet(3)/2-pos(3)/2);
                pos(2) = ceil(posClarinet(2)+posClarinet(4)/2-pos(4)/2);
                app.dlgAbout.UIFigure.Position = pos;
            end
            app.dlgAbout.UIFigure.Visible  = 'on';
        end

        % Value changed function: cmbProcessors
        function cmbProcessorsValueChanged(app, event)
            switch app.Mode.Value
                case 'Epochs'                    
                    import processors.*
                    import customProcessors.*        
                case 'Features'
                    import extractors.*
            end
            % Restrieve default settings
            filterName = app.cmbProcessors.Value;
            settings = feval(filterName);
            
            if numel(fieldnames(settings)) > 0
                app.btnSettings.Enable = 'on';
            else
                app.btnSettings.Enable = 'off';
            end
            
            app.cmbProcessors.Tooltip = help(filterName);
            app.btnSettings.UserData = settings;                    
        end

        % Button pushed function: btnProcess
        function btnProcessButtonPushed(app, event)
            switch app.Mode.Value
                case 'Epochs'
                    import processors.*
                    import customProcessors.*
                    
                    if isempty(app.pipeline)
                        app.btnBatchAddButtonPushed; % Add the current processo and use just that one
                    end
                    
                    selectedNodes = app.UITree.SelectedNodes;
                    for i=1:numel(selectedNodes)
                        % Use raw response if no previous filter was applied or user pressed the 'Raw' switch
                        epoch   = selectedNodes(i).NodeData;
                        if strcmp(app.btnResponseType.Value,'Raw') || ~epoch.hasDerivedResponse('filteredResponse', app.cmbDevices.Value)
                            response = epoch.getResponse(app.cmbDevices.Value);
                            epoch.addDerivedResponse('filteredResponse', response, app.cmbDevices.Value);
                        end
                        
                        % Apply all processors in the pipeline to the epoch
                        for j = 1:numel(app.pipeline)
                            proc    = app.pipeline(j);
                            epoch   = feval(proc.name, epoch, app.cmbDevices.Value, proc.settings);
                        end
                        epoch.attributes('pipeline') = app.pipeline;
                        selectedNodes(i).NodeData = epoch;
                    end
                    
                    
                    app.btnResponseType.Value = 'Processed';
                    app.btnResponseTypeValueChanged;
                    app.UITreeSelectionChanged;
                case 'Features'
                    import extractors.*
                    
                    % Ensure an EpochGroup was selected
                    group = app.UITree.SelectedNodes.NodeData;
                    if ~isa(group, 'EpochGroup')
                        return
                    end
                    
                    % Apply all extractors in the pipeline to selected EpochGroup
                    for j = 1:numel(app.pipeline)
                        proc    = app.pipeline(j);
                        group   = feval(proc.name, group, app.cmbDevices.Value, proc.settings);
                    end
                    app.UITree.SelectedNodes.NodeData = group;
                    app.UITreeSelectionChanged;
            end
        end

        % Button pushed function: btnSettings
        function btnSettingsButtonPushed(app, event)
            settings = app.btnSettings.UserData;
            
            newSettings = editStruct(settings);
            if ~isempty(newSettings)
                app.btnSettings.UserData = newSettings;
            end
        end

        % Button pushed function: btnFind
        function btnFindButtonPushed(app, event)
            if isempty(app.dlgFind) || ~isprop(app.dlgFind,'UIFigure')
                app.dlgFind = DialogFind(app);
            
                % Center the dialog on main window
                pos = app.dlgFind.UIFigure.Position;
                posClarinet = app.ClarinetUIFigure.Position;
                pos(1) = ceil(posClarinet(1)+posClarinet(3)/2-pos(3)/2);
                pos(2) = ceil(posClarinet(2)+posClarinet(4)/2-pos(4)/2);
                app.dlgFind.UIFigure.Position = pos;
            else
                app.dlgFind.UIFigure.Visible = 'on';
            end
        end

        % Value changed function: btnResponseType
        function btnResponseTypeValueChanged(app, event)
            switch app.btnResponseType.Value
                case 'Raw'
                    app.btnResponseType.FontColor = [0 0 0];
                case 'Processed'
                    app.btnResponseType.FontColor = [0.85 0.33 0.10];
            end
            app.UITreeSelectionChanged;
        end

        % Value changed function: cmbDevices
        function cmbDevicesValueChanged(app, event)
            app.UITreeSelectionChanged;
        end

        % Value changed function: XScaleType
        function XScaleTypeValueChanged(app, event)
            app.UITreeSelectionChanged;
        end

        % Button pushed function: btnSave
        function btnSaveButtonPushed(app, event)
            % Synch current cell with updated data from EpochTree nodes
            nodes = app.rootNodeCells.Children;
            for i = 1 : numel(nodes)
                app.Cell.epochs(i) = nodes(i).NodeData;
            end
            
            % Save cell on disk
            Cell = app.Cell; %#ok
            uisave({'Cell'},[app.Cell.h5File '.mat']);
            
            app.ClarinetUIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.ClarinetUIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
        end

        % Value changed function: btnEpochTypes
        function btnEpochTypesValueChanged(app, event)
            switch app.btnEpochTypes.Value
                case 'All'
                    app.btnEpochTypes.FontColor = [0 0 0];
                    if ~isempty(app.Cells)
                        app.populateUITree;
                    end
                case 'Searched'
                    app.btnEpochTypes.FontColor = [0.85 0.33 0.10];
                    if ~isempty(app.Cells)
                        app.populateUITree;
                    end
            end
        end

        % Selection changed function: btnGroupVisualizationType
        function btnGroupVisualizationTypeSelectionChanged(app, event)
            app.UITreeSelectionChanged;
        end

        % Close request function: ClarinetUIFigure
        function ClarinetUIFigureCloseRequest(app, event)
            delete(app.dlgAbout)
            delete(app.dlgFind)
            delete(app)
        end

        % Button pushed function: btnBatchAdd
        function btnBatchAddButtonPushed(app, event)
            % Append selected processor to the pipeline
            processor.name              = app.cmbProcessors.Value;
            processor.settings          = app.btnSettings.UserData;
            if isempty(app.pipeline)
                app.pipeline            = processor;
            else
                app.pipeline(end+1)     = processor;
            end
            
            tip = {'Process selected epochs with:', ''};
            for i=1:numel(app.pipeline)
                tip(end+1) = {[num2str(i) ') ' app.pipeline(i).name]}; %#ok
            end
            app.btnProcess.Tooltip      = tip;
            app.btnBatchSave.Enable     = 'on';
            app.btnBatchClear.Enable    = 'on';
            app.btnProcess.Enable       = 'on';
        end

        % Button pushed function: btnBatchClear
        function btnBatchClearButtonPushed(app, event)
            app.pipeline = [];
            app.btnProcess.Tooltip      = {'Process selected epochs with:'};
            app.btnBatchSave.Enable     = 'off';
            app.btnProcess.Enable       = 'off';
            app.btnBatchClear.Enable    = 'off';
        end

        % Selection changed function: pnlEpochTools
        function pnlEpochToolsSelectionChanged(app, event)
            selectedButton = app.pnlEpochTools.SelectedObject;
            for i=1:numel(app.UITree.SelectedNodes)
                if strcmp(selectedButton.Icon, 'white.png')
                    app.UITree.SelectedNodes(i).Icon = '';
                    app.UITree.SelectedNodes(i).NodeData.attributes('tagColor') = '';
                else
                    app.UITree.SelectedNodes(i).Icon = selectedButton.Icon;
                    [~,colorName,~] = fileparts(selectedButton.Icon);
                    app.UITree.SelectedNodes(i).NodeData.attributes('tagColor') = colorName;
                end
            end
        end

        % Button pushed function: btnBatchOpen
        function btnBatchOpenButtonPushed(app, event)
            [fName, PathName] = uigetfile({'*.mat','Clarinet pipelines (*.mat)'}, 'Select experiments', 'MultiSelect', 'off');
            app.ClarinetUIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.ClarinetUIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
            
            data = load(fullfile(PathName, fName), 'pipeline');
            app.pipeline = data.pipeline;
            
            tip = {'Add to processing pipeline', '', 'Current pipeline:'};
            for i=1:numel(app.pipeline)
                tip(end+1) = {[num2str(i) ') ' app.pipeline(i).name]}; %#ok
            end
            app.btnBatchAdd.Tooltip = tip;
            
            app.btnBatchAdd.Enable   = 'on';
            app.btnBatchClear.Enable = 'on';
            app.btnBatchSave.Enable  = 'on';
            app.btnProcess.Enable    = 'on';
        end

        % Button pushed function: btnBatchSave
        function btnBatchSaveButtonPushed(app, event)
            % Save processing pipeline on disk
            pipeline = app.pipeline; %#ok
            uisave({'pipeline'},'pipeline.mat');
            
            app.ClarinetUIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.ClarinetUIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
            
        end

        % Button pushed function: btnTagAdd
        function btnTagAddButtonPushed(app, event)
            Tag.Name = 'CustomTag';
            Tag.Value = 'true';
            
            newTag = editStruct(Tag, 'Add Tag');
            if ~isempty(newTag)
                % try to convert the value to number, if not possible store it as string
                value = str2double(newTag.Value);
                if ~isnan(value)
                    newTag.Value = value;
                end
                
                % Add the new Tag/Value pair to the list of attributes for selected epochs
                for i=1:numel(app.UITree.SelectedNodes)
                    app.UITree.SelectedNodes(i).NodeData.attributes(newTag.Name) = newTag.Value;
                end
                app.UITreeSelectionChanged;
            end
        end

        % Button pushed function: btnTagRemove
        function btnTagRemoveButtonPushed(app, event)
            if ~isempty(app.selectedTagRows)
                Tag = app.tblMeta.Data{app.selectedTagRows(:,1),1};
                Tag = Tag{:};
                for i=1:numel(app.UITree.SelectedNodes)
                    remove(app.UITree.SelectedNodes(i).NodeData.attributes, Tag);
                end
                app.UITreeSelectionChanged;
            end            
        end

        % Cell selection callback: tblMeta
        function tblMetaCellSelection(app, event)
            indices = event.Indices;
            app.selectedTagRows = indices;
        end

        % Button pushed function: btnBatchApply
        function btnBatchApplyButtonPushed(app, event)
            selectedNodes = app.UITree.SelectedNodes;
            % Load processing pipeline from selected epoch            
            if numel(selectedNodes) == 1
                epoch = selectedNodes.NodeData;     % Retrieve epoch data
                if epoch.hasAttribute('pipeline')
                    app.pipeline = epoch.attributes('pipeline');

                    tip = {'Process selected epochs with:', ''};
                    for i=1:numel(app.pipeline)
                        tip(end+1) = {[num2str(i) ') ' app.pipeline(i).name]}; %#ok
                    end
                    app.btnProcess.Tooltip = tip;
                    
                    app.btnBatchAdd.Enable   = 'on';
                    app.btnBatchClear.Enable = 'on';
                    app.btnBatchSave.Enable  = 'on';
                    app.btnProcess.Enable    = 'on';                    
                end
            end            
        end

        % Callback function
        function ModeTabsSelectionChanged(app, event)
            selectedTab = app.ModeTabs.SelectedTab;
            
            % Change toolbar buttons behavior depending of the mode in which Clarinet is bein used
            switch selectedTab.Title
                case 'Epochs'
                    app.lblProcessors.Text = 'Epoch processors';
                    app.btnSettings.Tooltip = 'Edit settings of selected processor';
                    app.btnProcess.Tooltip = 'Process selected epochs';
                    app.UITree.Multiselect = 'on';
                case 'Features'
                    app.lblProcessors.Text = 'Feature extractors';
                    app.btnSettings.Tooltip = 'Edit settings of selected extractor';
                    app.btnProcess.Tooltip = 'Extract selected features';
                    app.UITree.Multiselect = 'off';
            end
            app.populateFilterList;
        end

        % Callback function
        function btnExpAddButtonPushed(app, event)
            [fName, PathName] = uigetfile({'*.mat','Clarinet experiments (*.mat)'}, 'Select experiments', 'MultiSelect', 'on');
            app.Lamp.Color = 'Yellow';
            app.Log.Value = 'Loading experiments...';
            pause(0.2);
            
            app.ClarinetUIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.ClarinetUIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
            
            if iscell(fName)
                cd(PathName);
                nbfiles = length(fName);
            elseif fName ~= 0
                cd(PathName);
                nbfiles = 1;
            else
                nbfiles = 0;
            end
            
            if nbfiles == 1
                data = load(fullfile(PathName, fName), 'Cell');
                if isempty(app.Cells)
                    app.Cells = data.Cell;
                else
                    app.Cells(end+1) = data.Cell;
                end
                app.populateUITree;
            elseif nbfiles >1
                for i=1:nbfiles
                    data = load(fullfile(PathName, fName{i}), 'Cell');
                    if isempty(app.Cells)
                        app.Cells = data.Cell;
                    else
                        app.Cells(end+1) = data.Cell;
                    end
                end
                app.populateUITree;
            end
            
            app.Lamp.Color = 'Green';
            app.Log.Value = 'Loading experiments... DONE';
        end

        % Callback function
        function btnExpClearButtonPushed(app, event)
            app.Cells = [];
            app.populateUITree;
            app.btnExpClear.Enable  = 'off';
            app.btnTreeRemove.Enable = 'off';
        end

        % Button pushed function: btnTreeRemove
        function btnTreeRemoveButtonPushed(app, event)
            app.UITree.SelectedNodes.delete;
        end

        % Callback function
        function btnGroupAddButtonPushed(app, event)
            s.TagName   = 'device';
            s.TagValue  = 'Amplifier_Ch1';
            
            s = editStruct(s, 'Group epochs where');
            if ~isempty(s)
                % try to convert the value to number, if not possible store it as string
                value = str2double(s.TagValue);
                if ~isnan(value)
                    s.TagValue = value;
                end
                
                % Add the new Tag/Value pair to the list of attributes for selected epochs
                group = EpochGroup(s.TagName, s.TagValue);
                uitreenode(app.UITree.SelectedNodes,'Text',[s.TagName ' = ' s.TagValue],'NodeData',group, 'Icon', app.ICON_GROUP);
            end
        end

        % Button pushed function: btnTreeClear
        function btnTreeClearButtonPushed(app, event)
            app.Cells = [];
            app.SelectedEpochs = [];
            
            app.populateUITree;
            app.btnTreeClear.Enable = 'off';
        end

        % Callback function
        function AnalysisTreeSelectionChanged(app, event)
            selectedNode = app.AnalysisTree.SelectedNodes;

            if isempty(selectedNode.NodeData)
                % The root node was selected
                app.btnGroupAdd.Enable          = 'off';
                app.btnGroupByTag.Enable       = 'off';
                app.btnGroupAdd.Enable = 'off';
            elseif isa(selectedNode.NodeData, 'CellData')
                % An experiment was selected
                app.btnGroupAdd.Enable          = 'on';
                app.btnGroupByTag.Enable       = 'on';
                app.btnGroupAdd.Enable = 'on';
            elseif isa(selectedNode.NodeData, 'EpochGroup')
                % An EpochGroup was selected
                app.btnGroupAdd.Enable          = 'on';
                app.btnGroupByTag.Enable       = 'on';
                app.btnGroupAdd.Enable = 'on';
            end
            
        end

        % Button pushed function: btnGroupByTag
        function btnGroupByTagButtonPushed(app, event)
            if isempty(app.dlgGroupBy) || ~isprop(app.dlgGroupBy,'UIFigure')
                app.dlgGroupBy = DialogGroupBy(app);
            
                % Center the dialog on main window
                pos = app.dlgGroupBy.UIFigure.Position;
                posClarinet = app.ClarinetUIFigure.Position;
                pos(1) = ceil(posClarinet(1)+posClarinet(3)/2-pos(3)/2);
                pos(2) = ceil(posClarinet(2)+posClarinet(4)/2-pos(4)/2);
                app.dlgGroupBy.UIFigure.Position = pos;
            else
                app.dlgGroupBy.UIFigure.Visible = 'on';
            end
            
            % Populate combo box with available tags
            nodes = app.UITree.SelectedNodes;
            if numel(nodes) == 1
                parentCell = app.findParentCellData(nodes);
                [params, ~] = parentCell.getParamValues;
                tags = params;
            elseif numel(nodes) >1
                for i = 1:numel(nodes)
                    parentCell = app.findParentCellData(nodes(i));
                    [params, ~] = parentCell.getParamValues;
                    if i==1
                        tags = params;
                    else
                        tags = union(tags, params);
                    end
                end
            end
            app.dlgGroupBy.cmbTags.Items = sort(tags);
        end

        % Value changed function: Mode
        function ModeValueChanged(app, event)
            % Change toolbar buttons behavior depending of the mode in which Clarinet is bein used
            switch app.Mode.Value
                case 'Epochs'
                    app.Mode.FontColor          = [0 0 0];
                    app.lblProcessors.Text      = 'Epoch processors';
                    app.btnSettings.Tooltip     = 'Edit settings of selected processor';
                    app.btnProcess.Tooltip      = 'Process selected epochs';
                    app.UITree.Multiselect      = 'on';
                    app.pnlFeatureTools.Visible = 'off';
                    app.pnlEpochTools.Visible   = 'on';
                case 'Features'
                    app.Mode.FontColor          = [0.85 0.33 0.10];
                    app.lblProcessors.Text      = 'Feature extractors';
                    app.btnSettings.Tooltip     = 'Edit settings of selected extractor';
                    app.btnProcess.Tooltip      = 'Extract selected features';
                    app.UITree.Multiselect      = 'on';
                    app.pnlFeatureTools.Visible = 'on';
                    app.pnlEpochTools.Visible   = 'off';
                    app.populateUITree;
            end
            app.populateUITree;
            app.populateFilterList;
        end

        % Button pushed function: btnGroupAdd
        function btnGroupAddButtonPushed2(app, event)
            uitreenode(app.UITree.SelectedNodes,'Text','extractor','NodeData',[], 'Icon', app.ICON_EXTRACTOR);            
        end

        % Value changed function: cmbFeatures
        function cmbFeaturesValueChanged(app, event)
            if ~isempty(app.cmbFeatures.Value)
                group = app.UITree.SelectedNodes.NodeData;
                if isa(group, 'EpochGroup')
                    app.plotFeature(group, app.cmbFeatures.Value);
                end
            end
        end

        % Callback function
        function btnGroupByMultipleTagsButtonPushed(app, event)
            if isempty(app.dlgGroupBy) || ~isprop(app.dlgGroupBy,'UIFigure')
                app.dlgGroupBy = DialogGroupBy(app);
            
                % Center the dialog on main window
                pos = app.dlgGroupBy.UIFigure.Position;
                posClarinet = app.ClarinetUIFigure.Position;
                pos(1) = ceil(posClarinet(1)+posClarinet(3)/2-pos(3)/2);
                pos(2) = ceil(posClarinet(2)+posClarinet(4)/2-pos(4)/2);
                app.dlgGroupBy.UIFigure.Position = pos;
            else
                app.dlgGroupBy.UIFigure.Visible = 'on';
            end
            
            % Populate combo box with available tags
            nodes = app.UITree.SelectedNodes;
            if numel(nodes) == 1
                [params, ~] = nodes.NodeData.getParamValues;
                tags = params;
            elseif numel(nodes) >1
                for i = 1:numel(nodes)
                    [params, ~] = nodes(i).NodeData.getParamValues;
                    if i==1
                        tags = params;
                    else
                        tags = union(tags, params);
                    end
                end
            end
            app.dlgGroupBy.cmbTags.Items = sort(tags);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ClarinetUIFigure = uifigure;
            app.ClarinetUIFigure.Name = 'Clarinet';
            app.ClarinetUIFigure.CloseRequestFcn = createCallbackFcn(app, @ClarinetUIFigureCloseRequest, true);
            app.Lamp = uilamp(app.ClarinetUIFigure);
            app.Lamp.Tooltip = {'App status:'; 'Green = OK'; 'Yellow = Busy'; 'Red = Error'};
            app.Log = uieditfield(app.ClarinetUIFigure, 'text');
            app.Log.Tooltip = {'Status bar keeping track of last event occurred'};
            app.Log.Value = 'Welcome to Clarinet, open an experiment to begin';
            app.UIAxes = uiaxes(app.ClarinetUIFigure);
            app.pnlToolbar = uipanel(app.ClarinetUIFigure);
            app.btnFind = uibutton(app.pnlToolbar, 'push');
            app.btnFind.ButtonPushedFcn = createCallbackFcn(app, @btnFindButtonPushed, true);
            app.btnFind.Icon = '48px-search.png';
            app.btnFind.Enable = 'off';
            app.btnFind.Tooltip = {'Select epochs meeting specific conditions'};
            app.btnFind.Text = 'Search';
            app.btnOpenData = uibutton(app.pnlToolbar, 'push');
            app.btnOpenData.ButtonPushedFcn = createCallbackFcn(app, @btnOpenDataButtonPushed, true);
            app.btnOpenData.Icon = '48px-open.png';
            app.btnOpenData.Tooltip = {'Load epochs from an experiment'};
            app.btnOpenData.Text = 'Open';
            app.btnAbout = uibutton(app.pnlToolbar, 'push');
            app.btnAbout.ButtonPushedFcn = createCallbackFcn(app, @btnAboutButtonPushed, true);
            app.btnAbout.Icon = '48px-info.png';
            app.btnAbout.Tooltip = {'Information about Clarinet'};
            app.btnAbout.Text = 'About';
            app.btnExport = uibutton(app.pnlToolbar, 'push');
            app.btnExport.ButtonPushedFcn = createCallbackFcn(app, @btnExportButtonPushed, true);
            app.btnExport.Icon = '48px-export.png';
            app.btnExport.Enable = 'off';
            app.btnExport.Tooltip = {'Export selected epochs data to .mat file'};
            app.btnExport.Text = {'Export'; ''};
            app.btnSave = uibutton(app.pnlToolbar, 'push');
            app.btnSave.ButtonPushedFcn = createCallbackFcn(app, @btnSaveButtonPushed, true);
            app.btnSave.Icon = '48px-save.png';
            app.btnSave.Enable = 'off';
            app.btnSave.Tooltip = {'Save the experiment into .mat file'};
            app.btnSave.Text = 'Save';
            app.btnProcess = uibutton(app.pnlToolbar, 'push');
            app.btnProcess.ButtonPushedFcn = createCallbackFcn(app, @btnProcessButtonPushed, true);
            app.btnProcess.Icon = '48px-execute.png';
            app.btnProcess.Enable = 'off';
            app.btnProcess.Tooltip = {'Process selected epochs'};
            app.btnProcess.Text = 'Process';
            app.btnSettings = uibutton(app.pnlToolbar, 'push');
            app.btnSettings.ButtonPushedFcn = createCallbackFcn(app, @btnSettingsButtonPushed, true);
            app.btnSettings.Icon = '48px-settings.png';
            app.btnSettings.Tooltip = {'Edit settings for chosen filter'};
            app.btnSettings.Text = 'Settings';
            app.cmbProcessors = uidropdown(app.pnlToolbar);
            app.cmbProcessors.Items = {};
            app.cmbProcessors.ValueChangedFcn = createCallbackFcn(app, @cmbProcessorsValueChanged, true);
            app.cmbProcessors.Value = {};
            app.btnBatchAdd = uibutton(app.pnlToolbar, 'push');
            app.btnBatchAdd.ButtonPushedFcn = createCallbackFcn(app, @btnBatchAddButtonPushed, true);
            app.btnBatchAdd.Icon = '48px-List-add.png';
            app.btnBatchAdd.Enable = 'off';
            app.btnBatchAdd.Tooltip = {'Add processor to pipeline'};
            app.btnBatchAdd.Text = 'Add to Pipeline';
            app.btnBatchClear = uibutton(app.pnlToolbar, 'push');
            app.btnBatchClear.ButtonPushedFcn = createCallbackFcn(app, @btnBatchClearButtonPushed, true);
            app.btnBatchClear.Icon = '48px-list-clear.png';
            app.btnBatchClear.Enable = 'off';
            app.btnBatchClear.Tooltip = {'Clear processing pipeline'};
            app.btnBatchClear.Text = '';
            app.lblProcessors = uilabel(app.pnlToolbar);
            app.lblProcessors.Text = 'Epoch processors';
            app.btnBatchOpen = uibutton(app.pnlToolbar, 'push');
            app.btnBatchOpen.ButtonPushedFcn = createCallbackFcn(app, @btnBatchOpenButtonPushed, true);
            app.btnBatchOpen.Icon = '48px-open.png';
            app.btnBatchOpen.Enable = 'off';
            app.btnBatchOpen.Tooltip = {'Load a previously saved processing pipeline'};
            app.btnBatchOpen.Text = '';
            app.btnBatchSave = uibutton(app.pnlToolbar, 'push');
            app.btnBatchSave.ButtonPushedFcn = createCallbackFcn(app, @btnBatchSaveButtonPushed, true);
            app.btnBatchSave.Icon = '48px-save.png';
            app.btnBatchSave.Enable = 'off';
            app.btnBatchSave.Tooltip = {'Sace current pipeline'};
            app.btnBatchSave.Text = '';
            app.btnBatchApply = uibutton(app.pnlToolbar, 'push');
            app.btnBatchApply.ButtonPushedFcn = createCallbackFcn(app, @btnBatchApplyButtonPushed, true);
            app.btnBatchApply.Icon = '48px-pipeline-apply.png';
            app.btnBatchApply.Enable = 'off';
            app.btnBatchApply.Tooltip = {'Load processing pipeline used for selected epoch'};
            app.btnBatchApply.Text = '';
            app.XScaleType = uiswitch(app.ClarinetUIFigure, 'slider');
            app.XScaleType.Items = {'Time (s)', 'Samples'};
            app.XScaleType.ValueChangedFcn = createCallbackFcn(app, @XScaleTypeValueChanged, true);
            app.XScaleType.Tooltip = {'Switch the abscissa scale'};
            app.XScaleType.Value = 'Time (s)';
            app.btnGroupVisualizationType = uibuttongroup(app.ClarinetUIFigure);
            app.btnGroupVisualizationType.SelectionChangedFcn = createCallbackFcn(app, @btnGroupVisualizationTypeSelectionChanged, true);
            app.btnPlot2D = uitogglebutton(app.btnGroupVisualizationType);
            app.btnPlot2D.Text = '2D';
            app.btnPlot2D.Value = true;
            app.btnPlot3D = uitogglebutton(app.btnGroupVisualizationType);
            app.btnPlot3D.Text = '3D';
            app.UITree = uitree(app.ClarinetUIFigure);
            app.UITree.Multiselect = 'on';
            app.UITree.SelectionChangedFcn = createCallbackFcn(app, @UITreeSelectionChanged, true);
            app.pnlEpochTools = uibuttongroup(app.ClarinetUIFigure);
            app.pnlEpochTools.SelectionChangedFcn = createCallbackFcn(app, @pnlEpochToolsSelectionChanged, true);
            app.pnlEpochTools.Tooltip = {'Color Tag selected epochs'};
            app.btnTagEmpty = uitogglebutton(app.pnlEpochTools);
            app.btnTagEmpty.Icon = 'white.png';
            app.btnTagEmpty.Text = '';
            app.btnTagEmpty.Value = true;
            app.btnTagYellow = uitogglebutton(app.pnlEpochTools);
            app.btnTagYellow.Icon = 'yellow.png';
            app.btnTagYellow.Text = '';
            app.btnTagGreen = uitogglebutton(app.pnlEpochTools);
            app.btnTagGreen.Icon = 'green.png';
            app.btnTagGreen.Text = '';
            app.btnTagOrange = uitogglebutton(app.pnlEpochTools);
            app.btnTagOrange.Icon = 'orange.png';
            app.btnTagOrange.Text = '';
            app.btnTagBlue = uitogglebutton(app.pnlEpochTools);
            app.btnTagBlue.Icon = 'blue.png';
            app.btnTagBlue.Text = '';
            app.btnTagRed = uitogglebutton(app.pnlEpochTools);
            app.btnTagRed.Icon = 'red.png';
            app.btnTagRed.Text = '';
            app.btnTagBlack = uitogglebutton(app.pnlEpochTools);
            app.btnTagBlack.Icon = 'black.png';
            app.btnTagBlack.Text = '';
            app.btnEpochTypes = uiswitch(app.pnlEpochTools, 'slider');
            app.btnEpochTypes.Items = {'All', 'Searched'};
            app.btnEpochTypes.ValueChangedFcn = createCallbackFcn(app, @btnEpochTypesValueChanged, true);
            app.btnEpochTypes.Tooltip = {'Epochs to display'};
            app.btnEpochTypes.Value = 'All';
            app.btnTagRemove = uibutton(app.pnlEpochTools, 'push');
            app.btnTagRemove.ButtonPushedFcn = createCallbackFcn(app, @btnTagRemoveButtonPushed, true);
            app.btnTagRemove.Icon = '48px-tag-Remove.png';
            app.btnTagRemove.Enable = 'off';
            app.btnTagRemove.Tooltip = {'Remve selected tag from the list'};
            app.btnTagRemove.Text = '';
            app.btnTagAdd = uibutton(app.pnlEpochTools, 'push');
            app.btnTagAdd.ButtonPushedFcn = createCallbackFcn(app, @btnTagAddButtonPushed, true);
            app.btnTagAdd.Icon = '48px-tag-add.png';
            app.btnTagAdd.Enable = 'off';
            app.btnTagAdd.Tooltip = {'Add a custom tag to selected epochs'};
            app.btnTagAdd.Text = '';
            app.btnResponseType = uiswitch(app.pnlEpochTools, 'slider');
            app.btnResponseType.Items = {'Raw', 'Processed'};
            app.btnResponseType.ValueChangedFcn = createCallbackFcn(app, @btnResponseTypeValueChanged, true);
            app.btnResponseType.Tooltip = {'Epoch version to process'};
            app.btnResponseType.Value = 'Raw';
            app.tblMeta = uitable(app.ClarinetUIFigure);
            app.tblMeta.ColumnName = {'Tag'; 'Value'};
            app.tblMeta.RowName = {};
            app.tblMeta.CellSelectionCallback = createCallbackFcn(app, @tblMetaCellSelection, true);
            app.Mode = uiswitch(app.ClarinetUIFigure, 'slider');
            app.Mode.Items = {'Epochs', 'Features'};
            app.Mode.ValueChangedFcn = createCallbackFcn(app, @ModeValueChanged, true);
            app.Mode.Value = 'Epochs';
            app.pnlFeatureTools = uipanel(app.ClarinetUIFigure);
            app.pnlFeatureTools.Visible = 'off';
            app.btnTreeRemove = uibutton(app.pnlFeatureTools, 'push');
            app.btnTreeRemove.ButtonPushedFcn = createCallbackFcn(app, @btnTreeRemoveButtonPushed, true);
            app.btnTreeRemove.Icon = '48px-List-remove.png';
            app.btnTreeRemove.Enable = 'off';
            app.btnTreeRemove.Tooltip = {'Remve selected element from the analysis'};
            app.btnTreeRemove.Text = '';
            app.btnTreeClear = uibutton(app.pnlFeatureTools, 'push');
            app.btnTreeClear.ButtonPushedFcn = createCallbackFcn(app, @btnTreeClearButtonPushed, true);
            app.btnTreeClear.Icon = '48px-list-clear.png';
            app.btnTreeClear.Enable = 'off';
            app.btnTreeClear.Tooltip = {'Clear the analysis'};
            app.btnTreeClear.Text = '';
            app.btnGroupByTag = uibutton(app.pnlFeatureTools, 'push');
            app.btnGroupByTag.ButtonPushedFcn = createCallbackFcn(app, @btnGroupByTagButtonPushed, true);
            app.btnGroupByTag.Icon = '48px-tag-add.png';
            app.btnGroupByTag.Enable = 'off';
            app.btnGroupByTag.Tooltip = {'Group epochs by tag value'};
            app.btnGroupByTag.Text = '';
            app.btnGroupAdd = uibutton(app.pnlFeatureTools, 'push');
            app.btnGroupAdd.ButtonPushedFcn = createCallbackFcn(app, @btnGroupAddButtonPushed2, true);
            app.btnGroupAdd.Icon = '48px-CellData-average.png';
            app.btnGroupAdd.Enable = 'off';
            app.btnGroupAdd.Tooltip = {'Average features across experiments'};
            app.btnGroupAdd.Text = '';
            app.cmbFeatures = uidropdown(app.pnlFeatureTools);
            app.cmbFeatures.Items = {};
            app.cmbFeatures.ValueChangedFcn = createCallbackFcn(app, @cmbFeaturesValueChanged, true);
            app.cmbFeatures.Tooltip = {'Features available for selected group'};
            app.cmbFeatures.Value = {};
            app.cmbDevices = uidropdown(app.ClarinetUIFigure);
            app.cmbDevices.Items = {};
            app.cmbDevices.ValueChangedFcn = createCallbackFcn(app, @cmbDevicesValueChanged, true);
            app.cmbDevices.Tooltip = {'Device that generated epochs'};
            app.cmbDevices.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Clarinet

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ClarinetUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ClarinetUIFigure)
        end
    end
end